classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure    matlab.ui.Figure
        HOMEButton  matlab.ui.control.Button
        Label3      matlab.ui.control.Label
        Label2      matlab.ui.control.Label
        Label       matlab.ui.control.Label
        Button2     matlab.ui.control.Button
        Button1     matlab.ui.control.Button
        UIAxes2     matlab.ui.control.UIAxes
        UIAxes      matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        aBoard % Description
        Button1Clicks = 0;
        Button2Clicks = 0;
        a = arduino("COM16","Uno");
        a2 = arduino("COM13", "Mega2560");
        
        Health = 0;
        Money = 0;
        AirQuality = 0;
        
        dEcon = 0;
        
        HealthLights = 0;
        MoneyLights = 0;
        AirQualityLights = 0;
        
        mq2value = 0;
        threshold = 0;
    end
    
    methods (Access = private)
        
        function void = lightsOn(app, row, numLights)
            if (row == 1)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                    writeDigitalPin (app.a2, 'D2', false);
                    writeDigitalPin (app.a2, 'D25', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                    writeDigitalPin (app.a2, 'D2', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D7', true);
                    writeDigitalPin (app.a2, 'D8', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D7', true);
                    writeDigitalPin (app.a2, 'D8', true);
                end
            elseif (row == 2)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                    writeDigitalPin (app.a2, 'D10', false);
                    writeDigitalPin (app.a2, 'D9', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                    writeDigitalPin (app.a2, 'D10', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);

                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D15', true);
                    writeDigitalPin (app.a2, 'D16', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D15', true);
                    writeDigitalPin (app.a2, 'D16', true);
                end
  
            elseif (row == 3)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                    writeDigitalPin (app.a2, 'D18', false);
                    writeDigitalPin (app.a2, 'D17', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                    writeDigitalPin (app.a2, 'D18', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);   
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D23', true);
                    writeDigitalPin (app.a2, 'D24', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D23', true);
                    writeDigitalPin (app.a2, 'D24', true);
                end
            end
        end

        function void = bothButton(app)
            app.Button1Clicks = app.Button1Clicks + 1;
            app.Button2Clicks = app.Button2Clicks + 1;
        end
        
        function void = home(app)
            %show correct visual on the desired axes
            imshow('MATLAB_app\WelcomeScreen.PNG','Parent',app.UIAxes);
            app.Button1.Visible = 1;app.Label.Text = string(app.AirQuality);
            
            %reinitialize necessary variables to 0
            app.Button1Clicks = 0;
            app.Button2Clicks = 0;
            app.AirQuality = 0;
            app.Money = 0;
            app.Health = 0;
            
            %turn off LEDs
            lightsOn(app, 1, 0);
            lightsOn(app, 2, 0);
            lightsOn(app, 3, 8);
            
            %hide unwanted components
            app.Button2.Visible = 0;
            app.HOMEButton.Visible = 0;
            app.UIAxes2.Visible = 0;
            app.Label.Visible = 0;
            app.Label2.Visible = 0;
            app.Label3.Visible = 0;
            
            app.Button1.Text = 'START';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %app.aBoard=arduino('COM7', 'Mega2560');
            imshow('MATLAB_app\WelcomeScreen.PNG','Parent',app.UIAxes);
            app.Button1.Text = 'START';
            
            %turn off LEDs
            lightsOn(app, 1, 0);
            lightsOn(app, 2, 0);
            lightsOn(app, 3, 8);
            
            %hide unwanted components
            app.Button2.Visible = 0;
            app.HOMEButton.Visible = 0;
            app.UIAxes2.Visible = 0;
            app.Label.Visible = 0;
            app.Label2.Visible = 0;
            app.Label3.Visible = 0;
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            if app.Button1Clicks == 0 %when on welcome screen
                imshow('MATLAB_app\SelectionScreen.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.HOMEButton.Visible = 1;
                app.Button1.Text = 'Play Quiz';
                app.Button2.Text = 'Sensor';
                
            elseif app.Button1Clicks == 1 %when on this/that selection screen
                imshow('MATLAB_app\QuizIntro.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                app.Button2Clicks = 1;
                
            elseif app.Button1Clicks == 2 %when on the quiz introduction screen
                imshow('MATLAB_app\QuizFood.PNG','Parent',app.UIAxes);
                app.Label.Text = string(app.AirQuality);
                
            elseif app.Button1Clicks == 3 %when on the food introduction screen
                imshow('MATLAB_app\Quiz1.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.Button1.Text = 'Chicken';
                app.Button2.Text = 'Beef';
                
                
            elseif app.Button1Clicks == 4 %when on the first quiz question
                imshow('MATLAB_app\Quiz2.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Orange Juice';
                app.Button2.Text = 'Coffee';
                
                app.AirQuality = app.AirQuality + 762;
                               
                app.Button2Clicks = app.Button2Clicks + 1;
                
            elseif app.Button1Clicks == 5 %when on the second quiz question
                imshow('MATLAB_app\Quiz3.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Rice';
                app.Button2.Text = 'Pasta';
                
                app.AirQuality = app.AirQuality + 30;
                               
                app.Button2Clicks = app.Button2Clicks + 1;
                
            elseif app.Button1Clicks == 6 %when on the third quiz question
                imshow('MATLAB_app\QuizTransportation.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                app.AirQuality = app.AirQuality + 186;
                
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 7 %when on the transportation introduction screen
                imshow('MATLAB_app\Quiz4.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.Button1.Text = 'No Car';
                app.Button2.Text = 'Electric Car';                
                
            elseif app.Button1Clicks == 8 %when on the fourth quiz question
                imshow('MATLAB_app\Quiz5.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Train';
                app.Button2.Text = 'Bus';
                
                app.AirQuality = app.AirQuality + 8100;
                app.dEcon = app.dEcon - 0.12; 
                
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 9 %when on the fifth quiz question
                imshow('MATLAB_app\QuizEnergy.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                                
                app.AirQuality = app.AirQuality - 1755;
                
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 10 %When on the energy efficient practices introduction screen
                imshow('MATLAB_app\Quiz6.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Technology';
                app.Button2.Text = 'Structural';
                
                app.Button2.Visible = 1;
                
            elseif app.Button1Clicks == 11 %When on the sixth quiz question
                imshow('MATLAB_app\Quiz7.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Public';
                app.Button2.Text = 'Homes';
                
                app.AirQuality = app.AirQuality + 2025;
                
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 12 %when on the LAST quiz question
                imshow('MATLAB_app\Results.PNG','Parent',app.UIAxes);
                app.AirQuality = app.AirQuality - 802;
                app.dEcon = app.dEcon + 0.1;
                
                app.Button2Clicks = app.Button2Clicks +1;
                
                app.Health = int8(app.AirQuality * 0.001494);
                app.Money = (app.AirQuality * 0.00017036) + app.dEcon;
                
                %truncates to 2 decimal places
                app.Money = fix(app.Money * 10^2) / 10^2;
                
                app.AirQualityLights = int8(app.AirQuality * 0.0005569);
                app.HealthLights = int8(app.Health * 0.380952);
                app.MoneyLights = int8(app.Money * 2.85714);
                
                app.Button1.Visible = 0;
                app.Button2.Visible = 0;
                    
                app.Label.Visible = 1;
                app.Label2.Visible = 1;
                app.Label3.Visible = 1;
                
                app.Label.Text = string(app.AirQuality);
                app.Label2.Text = string(app.Health);
                app.Label3.Text = string(app.Money);
                
                lightsOn(app, 3, app.MoneyLights);
                lightsOn(app, 2, app.HealthLights);
                lightsOn(app, 1, app.AirQualityLights);
            end
            app.Button1Clicks = app.Button1Clicks + 1;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            if app.Button2Clicks == 0 %when on this/that selection screen
                app.UIAxes.Visible = 0;
                app.UIAxes2.Visible = 1;
                app.Button1.Visible = 0;
                app.Button2.Visible = 0;
                
                %figure 1;
                app.UIAxes2.Title.String = 'Arduino Data Log';
                app.UIAxes2.XLabel.String = 'Elapsed Time (s)';
                app.UIAxes2.YLabel.String = 'Output of Sensor';
                
                for i=0:200
                    app.mq2value = app.mq2value + readVoltage(app.a, 'A0');
                end
                app.mq2value=app.mq2value/100;
                
                app.threshold=app.mq2value*1.005;
                data = [];
                time = [];
                tic;
                
                data(1) = readVoltage(app.a, 'A0');
                time(1) = toc;
                plot(time(1:1),data(1:1),'-r');
                
                for i=2:150
                   data(i)=readVoltage(app.a, 'A0');
                   time(i)=toc;
                   plot(app.UIAxes2,time(1:i),data(1:i),'-r');
                   drawnow;
%                    if(data(i)>=app.threshold)
%                        writeDigitalPin(app.a,'D13',1);
%                    else
%                        writeDigitalPin(app.a,'D13',0);
%                    end
                   if i>100
                       writeDigitalPin(app.a,'D13',1)
                   else
                      writeDigitalPin(app.a,'D13',0) 
                   end
                   
                   
                end
                writeDigitalPin(app.a,'D13',0);
                cla(app.UIAxes2, 'reset');
                app.UIAxes2.Visible = 0;
                app.UIAxes2.Box = 0;
                app.Button1.Visible = 1;
                app.Button2.Visible = 1;
                
            elseif app.Button2Clicks == 1 %When on the first quiz question
                imshow('MATLAB_app\Quiz2.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Orange Juice';
                app.Button2.Text = 'Coffee';
                
                app.AirQuality = app.AirQuality + 4317.6;
                bothButton(app);
                
            elseif app.Button2Clicks == 2 %When on the second quiz question
                imshow('MATLAB_app\Quiz3.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Rice';
                app.Button2.Text = 'Pasta';
                
                app.AirQuality = app.AirQuality + 238.2;
                bothButton(app);
                
            elseif app.Button2Clicks == 3 %When on the third quiz question
                imshow('MATLAB_app\QuizTransportation.PNG', 'Parent', app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                app.AirQuality = app.AirQuality + 67.2;
                bothButton(app);
                
            elseif app.Button2Clicks == 4 %When on the fourth quiz question
                imshow('MATLAB_app\Quiz5.PNG','Parent', app.UIAxes);
                app.Button1.Text = 'Train';
                app.Button2.Text = 'Bus';
                
                app.AirQuality = app.AirQuality + 4050;
                app.dEcon = app.dEcon + .18;
                
                bothButton(app);
                
            elseif app.Button2Clicks == 5 %When on the fifth quiz question
                imshow('MATLAB_app\QuizEnergy.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                app.AirQuality = app.AirQuality - 4725;
                bothButton(app);
                
            elseif app.Button2Clicks == 6 %When on the sixth quiz question
                imshow('MATLAB_app\Quiz7.PNG','Parent', app.UIAxes);
                app.Button1.Text = 'Public';
                app.Button2.Text = 'Homes';
                
                app.AirQuality = app.AirQuality + 2970;
                app.dEcon = app.dEcon + 0.07;
                
                bothButton(app);             
                
            elseif app.Button2Clicks == 7 %when on the LAST quiz question
                imshow('MATLAB_app\Results.PNG','Parent',app.UIAxes);
                app.dEcon = app.dEcon + 0.15;
                bothButton(app);
                
                app.Health = int8(app.AirQuality * 0.001494);
                app.Money = (app.AirQuality * 0.00017036);
                
                %truncates to 2 decimal places
                app.Money = fix(app.Money * 10^2) / 10^2;
                
                app.AirQualityLights = int8(app.AirQuality * 0.0005569);
                app.HealthLights = int8(app.Health * 0.380952);
                app.MoneyLights = 8 - int8(app.Money * 2.85714);
                
                app.Button1.Visible = 0;
                app.Button2.Visible = 0;
                
                app.Label.Visible = 1;
                app.Label2.Visible = 1;
                app.Label3.Visible = 1;
                
                lightsOn(app, 3, app.MoneyLights);
                lightsOn(app, 2, app.HealthLights);
                lightsOn(app, 1, app.AirQualityLights);
                
                app.Label.Text = string(app.AirQuality);
                app.Label2.Text = string(app.Health);
                app.Label3.Text = string(app.Money);                
            end
        end

        % Callback function
        function UIAxesButtonDown(app, event)
            
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            home(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end