classdef app1Final < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure          matlab.ui.Figure
        UseAudioCheckBox  matlab.ui.control.CheckBox
        HOMEButton        matlab.ui.control.Button
        Label3            matlab.ui.control.Label
        Label2            matlab.ui.control.Label
        Label             matlab.ui.control.Label
        Button2           matlab.ui.control.Button
        Button1           matlab.ui.control.Button
        UIAxes2           matlab.ui.control.UIAxes
        UIAxes            matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %these are the initializations of all the variables which are
        %called in various places throughout the code
        
        %counters for the number of times that each button has been clicked
        Button1Clicks = 0;
        Button2Clicks = 0;
        
        %the two variables used to refer to the two arduino boards used
        a = arduino("COM8","Uno");
        a2 = arduino("COM13", "Mega2560");
        
        %variables which are used throughout the quiz to calculate the
        %united values for these respecitve categories
        Health = 0;
        Money = 0;
        AirQuality = 0;
        
        %special variable for money which holds the information about money
        %which is not directly dependent on air pollution. Added to to the
        %moeny value at the end to get the final value 
        dEcon = 0;
        
        %values which are used to hold the integers indicating how many 
        %lights should be lit up on the board at the end of the exhibit.
        %These values were found by mapping the united values to a 1-8
        %score
        HealthLights = 0;
        MoneyLights = 0;
        AirQualityLights = 0;
        
        %various values needed for the MQ2 sensor graph 
        mq2value = 0;
        threshold = 0;
        time = 0;
    end
    
    methods (Access = private)
        
        function lightsOn(app, row, numLights)
            %This function is used to turn on a certian number of lights in
            %a given row.
            
            %The variable 'app' is not meaningful. It is just
            %necessary for running functions in app designer.
            
            %The variable'row' references which color lights are being 
            %turned on. It expects an integer input of 1 (blue), 2 
            %(yellow), or 3(green).
            
            %The variable 'numLights' references how many lights of the
            %predetermined color will be turned on. It expects an integer
            %input between 1 and 8.
            
            if (row == 1)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                    writeDigitalPin (app.a2, 'D2', false);
                    writeDigitalPin (app.a2, 'D25', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                    writeDigitalPin (app.a2, 'D2', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                    writeDigitalPin (app.a2, 'D3', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                    writeDigitalPin (app.a2, 'D4', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                    writeDigitalPin (app.a2, 'D5', false);
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                    writeDigitalPin (app.a2, 'D6', false);
                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D8', false);
                    writeDigitalPin (app.a2, 'D7', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D7', true);
                    writeDigitalPin (app.a2, 'D8', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D25', true);
                    writeDigitalPin (app.a2, 'D2', true);
                    writeDigitalPin (app.a2, 'D3', true);
                    writeDigitalPin (app.a2, 'D4', true);
                    writeDigitalPin (app.a2, 'D5', true);
                    writeDigitalPin (app.a2, 'D6', true);
                    writeDigitalPin (app.a2, 'D7', true);
                    writeDigitalPin (app.a2, 'D8', true);
                end
            elseif (row == 2)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                    writeDigitalPin (app.a2, 'D10', false);
                    writeDigitalPin (app.a2, 'D9', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                    writeDigitalPin (app.a2, 'D10', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                    writeDigitalPin (app.a2, 'D11', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                    writeDigitalPin (app.a2, 'D12', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);
                    writeDigitalPin (app.a2, 'D13', false);
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                    writeDigitalPin (app.a2, 'D14', false);

                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D16', false);
                    writeDigitalPin (app.a2, 'D15', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D15', true);
                    writeDigitalPin (app.a2, 'D16', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D9', true);
                    writeDigitalPin (app.a2, 'D10', true);
                    writeDigitalPin (app.a2, 'D11', true);
                    writeDigitalPin (app.a2, 'D12', true);
                    writeDigitalPin (app.a2, 'D13', true);
                    writeDigitalPin (app.a2, 'D14', true);
                    writeDigitalPin (app.a2, 'D15', true);
                    writeDigitalPin (app.a2, 'D16', true);
                end
  
            elseif (row == 3)
                if (numLights == 0)
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                    writeDigitalPin (app.a2, 'D18', false);
                    writeDigitalPin (app.a2, 'D17', false);
                elseif (numLights == 1)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                    writeDigitalPin (app.a2, 'D18', false);
                elseif (numLights == 2)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                    writeDigitalPin (app.a2, 'D19', false);
                elseif (numLights == 3)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);
                    writeDigitalPin (app.a2, 'D20', false);
                elseif (numLights == 4)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                    writeDigitalPin (app.a2, 'D21', false);   
                elseif (numLights == 5)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                    writeDigitalPin (app.a2, 'D22', false);
                elseif (numLights == 6)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D24', false);
                    writeDigitalPin (app.a2, 'D23', false);
                elseif (numLights == 7)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D23', true);
                    writeDigitalPin (app.a2, 'D24', false);
                elseif (numLights == 8)
                    writeDigitalPin (app.a2, 'D17', true);
                    writeDigitalPin (app.a2, 'D18', true);
                    writeDigitalPin (app.a2, 'D19', true);
                    writeDigitalPin (app.a2, 'D20', true);
                    writeDigitalPin (app.a2, 'D21', true);
                    writeDigitalPin (app.a2, 'D22', true);
                    writeDigitalPin (app.a2, 'D23', true);
                    writeDigitalPin (app.a2, 'D24', true);
                end
            end
        end

        function bothButton(app) 
            %simple cleanup code that incremetnts both button click counters
            app.Button1Clicks = app.Button1Clicks + 1;
            app.Button2Clicks = app.Button2Clicks + 1;
        end
        
        function home(app)
            %code to be executed in order to effectively reset the app. Can
            %be called at any point.
            
            %show correct visual on the desired axes, and display desired
            %components
            imshow('MATLAB_app\WelcomeScreen.PNG','Parent',app.UIAxes);
            app.Button1.Visible = 1;
            app.Button1.Text = 'START';
            app.Button2.Visible = 0;
            app.Label.Text = string(app.AirQuality);
            app.UseAudioCheckBox.Visible = 1;
            
            %reinitialize necessary variables to 0
            app.Button1Clicks = 0;
            app.Button2Clicks = 0;
            app.AirQuality = 0;
            app.Money = 0;
            app.Health = 0;
            
            %turn off graph (if applicable)
            data = zeros(1,450);
            timeplot = zeros(1,450);
            plot(app.UIAxes2, timeplot, data);
            
            %turn off sound (if applicable), and play intro mp3
            clear sound;
            [b, Fs] = audioread ("RecordingA.mp3");
            sound(b, Fs);
            
            %turn off LEDs
            lightsOn(app, 1, 0);
            lightsOn(app, 2, 0);
            lightsOn(app, 3, 8);
            
            %hide unwanted components
            app.Button2.Visible = 0;
            app.HOMEButton.Visible = 0;
            app.UIAxes2.Visible = 0;
            app.Label.Visible = 0;
            app.Label2.Visible = 0;
            app.Label3.Visible = 0;
        end
        
        function timer(app)
            %infinite while loop which continuously checks if there has
            %been any user interaction in the last 30 seconds. Returns to
            %the home screen if not
            while true
                app.time = app.time + 1;
                if app.time >= 30 %change this value to change reset time
                    home(app);
                    app.time = 0;
                end
                pause (1);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            %Show necessary image to axes and correct button text
            imshow('MATLAB_app\WelcomeScreen.PNG','Parent',app.UIAxes);
            app.Button1.Text = 'START';
            
            %turn off LEDs
            lightsOn(app, 1, 0);
            lightsOn(app, 2, 0);
            lightsOn(app, 3, 8);
            
            %hide unwanted components
            app.Button2.Visible = 0;
            app.HOMEButton.Visible = 0;
            app.UIAxes2.Visible = 0;
            app.Label.Visible = 0;
            app.Label2.Visible = 0;
            app.Label3.Visible = 0;
            
            %play audio file
            [b, Fs] = audioread ("RecordingA.mp3");
            sound(b, Fs);
            
            %start the audio reset function
            timer(app);          
        end

        % Button pushed function: Button1
        function Button1Pushed(app, event)
            %reinitialize time since user interaction to 0
            app.time = 0;
                
            %turn off any previous audio (if applicable)
            clear sound;
            
            if app.Button1Clicks == 0 %when on welcome screen                
                %show the necessary components and set accurate text
                imshow('MATLAB_app\SelectionScreen.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.HOMEButton.Visible = 1;
                app.Button1.Text = 'Play Quiz';
                app.Button2.Text = 'Sensor';
                app.UseAudioCheckBox.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingB.mp3");
                    sound(b, Fs);
                end
                
            elseif app.Button1Clicks == 1 %when on this/that selection screen
                %show the necessary components and set accurate text
                imshow('MATLAB_app\QuizIntro.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                app.Button2Clicks = 1;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingD.mp3");
                    sound(b, Fs);
                end
                
            elseif app.Button1Clicks == 2 %when on the quiz introduction screen                
                %show required compents and set accurate text
                imshow('MATLAB_app\QuizFood.PNG','Parent',app.UIAxes);
                app.time = 0;
                app.Label.Text = string(app.AirQuality);
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingE.mp3");
                    sound(b, Fs);
                end
                
            elseif app.Button1Clicks == 3 %when on the food introduction screen
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz1.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.Button1.Text = 'Chicken';
                app.Button2.Text = 'Beef';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingF.mp3");
                    sound(b, Fs);
                end
                
                
            elseif app.Button1Clicks == 4 %when on the first quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz2.PNG','Parent',app.UIAxes);
                app.time = 0;
                app.Button1.Text = 'Orange Juice';
                app.Button2.Text = 'Coffee';
                clear sound;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingG.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 762;
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks + 1;
                
            elseif app.Button1Clicks == 5 %when on the second quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz3.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Rice';
                app.Button2.Text = 'Pasta';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingH.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 30;
                      
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks + 1;
                
            elseif app.Button1Clicks == 6 %when on the third quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\QuizTransportation.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingI.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 186;
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 7 %when on the transportation introduction screen
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz4.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.Button1.Text = 'No Car';
                app.Button2.Text = 'Electric Car'; 
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingJ.mp3");
                    sound(b, Fs);
                end
                
            elseif app.Button1Clicks == 8 %when on the fourth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz5.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Train';
                app.Button2.Text = 'Bus';
               
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingK.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                %Also changes the dEcon value to express an economic effect
                app.AirQuality = app.AirQuality + 8100;
                app.dEcon = app.dEcon - 0.12; 
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 9 %when on the fifth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\QuizEnergy.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingL.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality - 1755;
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 10 %When on the energy efficient practices introduction screen
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz6.PNG','Parent',app.UIAxes);
                app.Button2.Visible = 1;
                app.Button1.Text = 'Technology';
                app.Button2.Text = 'Structural';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingM.mp3");
                    sound(b, Fs);
                end             
                
            elseif app.Button1Clicks == 11 %When on the sixth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz7.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Public';
                app.Button2.Text = 'Homes';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingN.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 2025;
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                app.Button2Clicks = app.Button2Clicks +1;
                
            elseif app.Button1Clicks == 12 %when on the LAST quiz question
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                %Also changes the dEcon value to express an economic effect
                app.AirQuality = app.AirQuality - 802;
                app.dEcon = app.dEcon + 0.1;
                
                %increment the other button click variable. This allows the
                %screens to be in sync with eachother across the two
                %functions.                
                app.Button2Clicks = app.Button2Clicks +1;
                
                %calculates the values for health and money based off of
                %the air quality data (and using the dEcon value for money)
                app.Health = int8(app.AirQuality * 0.001494);
                app.Money = (app.AirQuality * 0.00017036) + app.dEcon;
                
                %truncates to 2 decimal places
                app.Money = fix(app.Money * 10^2) / 10^2;
                
                %manipulates these values so that they are intergers, 1-8
                app.AirQualityLights = int8(app.AirQuality * 0.0005569);
                app.HealthLights = int8(app.Health * 0.380952);
                app.MoneyLights = int8(app.Money * 2.85714);
                
                
                %show required compents and set accurate text
                imshow('MATLAB_app\Results.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                app.Label.Visible = 1;
                app.Label2.Visible = 1;
                app.Label3.Visible = 1;
                
                app.Label.Text = string(app.AirQuality);
                app.Label2.Text = string(app.Health);
                app.Label3.Text = string(app.Money);
                
                %turn on the appropriate lights
                lightsOn(app, 3, app.MoneyLights);
                lightsOn(app, 2, app.HealthLights);
                lightsOn(app, 1, app.AirQualityLights);

            elseif app.Button1Clicks == 13 %When on results screen
                %show required compents and set accurate text
                imshow('MATLAB_app\Feedback.png','Parent',app.UIAxes);
                app.Button1.Visible = 0;
                app.Label.Visible = 0;
                app.Label2.Visible = 0;
                app.Label3.Visible = 0;
            end
            
            %increments the counter for this button click every time the
            %button is clicked.
            app.Button1Clicks = app.Button1Clicks + 1;
        end

        % Button pushed function: Button2
        function Button2Pushed(app, event)
            %reinitialize time since user interaction to 0
            app.time = 0;
                
            %turn off any previous audio (if applicable)
            clear sound;
            
            if app.Button2Clicks == 0 %when on this/that selection screen                
                %show required compents
                app.UIAxes.Visible = 0;
                app.UIAxes2.Visible = 1;
                app.Button1.Visible = 0;
                app.Button2.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingC.mp3");
                    sound(b, Fs);
                end
                
                %put appropriate labels to the correct chart elements
                app.UIAxes2.Title.String = 'Arduino Data Log';
                app.UIAxes2.XLabel.String = 'Elapsed Time (s)';
                app.UIAxes2.YLabel.String = 'Output of Sensor';
                
                %effectivly 'calibrates' the MQ2 sensor to its environment
                %by taking the average of 100 readings to be the true
                %ambient value (measures 'lack of oxygen')
                for i=0:100
                    app.mq2value = app.mq2value + readVoltage(app.a, 'A0');
                    if app.UseAudioCheckBox.Visible == 1
                        %if the user returns to the home screen during this
                        %process, stop the process.
                        break
                    end
                end
                app.mq2value=app.mq2value/100;
                
                %sets the value which much be exceeded in order to trigger 
                %the fan to clear the air. Currently set to a .5% increase
                %over ambient conditions. 
                app.threshold=app.mq2value*1.005;
                
                %creates empty arrays which will be filled with the data to
                %be plotted (helps deal with computational inefficency from
                %arrays that change size every iteration).
                data = zeros(1,450);
                timeplot = zeros(1,450);
                
                %starts the timer used to plot this data as a function of
                %time
                tic;
                
                %takes 450 readings of the MQ2 sensor
                for i=1:450
                    %reinitialize time since user interaction to 0 (makes
                    %sure that the code doesn't autoreset during this
                    %process)
                    app.time = 0;
                    
                    %fill the precreated arays with values measured by the
                    %sensor and time values, and plot these to the graph
                    data(i)=readVoltage(app.a, 'A0');
                    timeplot(i)=toc;
                    plot(app.UIAxes2,timeplot(1:i),data(1:i),'-r');
                    
                    %if the MQ2 for this reading passes the threshold, turn
                    %on the fan. If it doesn't turn off the fan (if it is
                    %on)
                    if(data(i)>=app.threshold)
                        writeDigitalPin(app.a,'D13',1);
                    else
                        writeDigitalPin(app.a,'D13',0);
                    end
                   
                    if app.UseAudioCheckBox.Visible == 1
                        %again, if the user returns to the home screen
                        %during this process, stop the process.
                        break
                   end
                   
                end
                
                %at the end of this process...
                %turn off the fan
                writeDigitalPin(app.a,'D13',0);
                %reinitialize the arrays to empty arrays of the desired
                %size
                data = zeros(1,450);
                timeplot = zeros(1,450);
                %plot the empty arrays (effectively erases the line drawn
                %by the previous plot functions
                plot(app.UIAxes2, timeplot, data);
                %show required compents
                app.UIAxes2.Visible = 0;
                app.UIAxes2.Box = 0;
                app.Button1.Visible = 1;
                app.Button2.Visible = 1;
                
            elseif app.Button2Clicks == 1 %When on the first quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz2.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Orange Juice';
                app.Button2.Text = 'Coffee';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingF.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 4317.6;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
            elseif app.Button2Clicks == 2 %When on the second quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz3.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Rice';
                app.Button2.Text = 'Pasta';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingH.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 238.2;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
            elseif app.Button2Clicks == 3 %When on the third quiz question               
                %show required compents and set accurate text
                imshow('MATLAB_app\QuizTransportation.PNG', 'Parent', app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingI.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 67.2;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
            elseif app.Button2Clicks == 4 %When on the fourth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz5.PNG','Parent', app.UIAxes);
                app.Button1.Text = 'Train';
                app.Button2.Text = 'Bus';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingK.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 4050;
                app.dEcon = app.dEcon + .18;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
            elseif app.Button2Clicks == 5 %When on the fifth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\QuizEnergy.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingL.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality - 4725;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
            elseif app.Button2Clicks == 6 %When on the sixth quiz question
                %show required compents and set accurate text
                imshow('MATLAB_app\Quiz7.PNG','Parent', app.UIAxes);
                app.Button1.Text = 'Public';
                app.Button2.Text = 'Homes';
                
                %if the user elected to use audio on the welcome screen,
                %play the correct file.
                if app.UseAudioCheckBox.Value == 1
                    [b, Fs] = audioread ("RecordingN.mp3");
                    sound(b, Fs);
                end
                
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.AirQuality = app.AirQuality + 2970;
                app.dEcon = app.dEcon + 0.07;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);             
                
            elseif app.Button2Clicks == 7 %when on the LAST quiz question
                %Add the correct amount to the air quality variable. All
                %other values will be calculated off of this one at the end
                app.dEcon = app.dEcon + 0.15;
                
                %increment both button click variables. This allows the
                %screens to be in sync with eachother across the two
                %functions.
                bothButton(app);
                
                %calculates the values for health and money based off of
                %the air quality data (and using the dEcon value for money)
                app.Health = int8(app.AirQuality * 0.001494);
                app.Money = (app.AirQuality * 0.00017036);
                
                %truncates to 2 decimal places
                app.Money = fix(app.Money * 10^2) / 10^2;
                
                %manipulates these values so that they are integers, 1-8
                app.AirQualityLights = int8(app.AirQuality * 0.0005569);
                app.HealthLights = int8(app.Health * 0.380952);
                app.MoneyLights = 8 - int8(app.Money * 2.85714);
                
                %show required components and accurate text
                imshow('MATLAB_app\Results.PNG','Parent',app.UIAxes);
                app.Button1.Text = 'Continue';
                app.Button2.Visible = 0;
                
                app.Label.Visible = 1;
                app.Label2.Visible = 1;
                app.Label3.Visible = 1;
                
                 lightsOn(app, 3, app.MoneyLights);
                 lightsOn(app, 2, app.HealthLights);
                 lightsOn(app, 1, app.AirQualityLights);
                
                app.Label.Text = string(app.AirQuality);
                app.Label2.Text = string(app.Health);
                app.Label3.Text = string(app.Money);
            end
        end

        % Button pushed function: HOMEButton
        function HOMEButtonPushed(app, event)
            home(app);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Title')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.Button1 = uibutton(app.UIFigure, 'push');
            app.Button1.ButtonPushedFcn = createCallbackFcn(app, @Button1Pushed, true);
            app.Button1.Text = 'Button1';
            app.Button2 = uibutton(app.UIFigure, 'push');
            app.Button2.ButtonPushedFcn = createCallbackFcn(app, @Button2Pushed, true);
            app.Button2.Visible = 'off';
            app.Button2.Text = 'Button2';
            app.Label = uilabel(app.UIFigure);
            app.Label2 = uilabel(app.UIFigure);
            app.Label2.Text = 'Label2';
            app.Label3 = uilabel(app.UIFigure);
            app.Label3.Text = 'Label3';
            app.HOMEButton = uibutton(app.UIFigure, 'push');
            app.HOMEButton.ButtonPushedFcn = createCallbackFcn(app, @HOMEButtonPushed, true);
            app.HOMEButton.Text = 'HOME';
            app.UseAudioCheckBox = uicheckbox(app.UIFigure);
            app.UseAudioCheckBox.Text = 'Use Audio?';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1Final

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end